classdef OSA_dataExplorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OSAdataexplorerUIFigure      matlab.ui.Figure
        MainGridLayout               matlab.ui.container.GridLayout
        PanelsGridLayout             matlab.ui.container.GridLayout
        MainInfo                     matlab.ui.control.Label
        TabGroup                     matlab.ui.container.TabGroup
        HomeTab                      matlab.ui.container.Tab
        HomeGrid                     matlab.ui.container.GridLayout
        Home2Grid                    matlab.ui.container.GridLayout
        GridLayout13                 matlab.ui.container.GridLayout
        LegendButton                 matlab.ui.control.StateButton
        TypeOfTraceGrid              matlab.ui.container.GridLayout
        GridLayout12                 matlab.ui.container.GridLayout
        FitSpinner                   matlab.ui.control.Spinner
        FSRButton                    matlab.ui.control.StateButton
        FitCurveButton               matlab.ui.control.StateButton
        SmoothLabel                  matlab.ui.control.Label
        FourierGrid                  matlab.ui.container.GridLayout
        FourierGrid2                 matlab.ui.container.GridLayout
        GridLayout11                 matlab.ui.container.GridLayout
        NormalizedButton             matlab.ui.control.StateButton
        oneharmonicButton            matlab.ui.control.StateButton
        NumberofharmoicSpinner       matlab.ui.control.Spinner
        NumberofharmoicSpinnerLabel  matlab.ui.control.Label
        GridLayout10                 matlab.ui.container.GridLayout
        IFFTButton                   matlab.ui.control.StateButton
        FFTButton                    matlab.ui.control.StateButton
        FourierTransformsLabel       matlab.ui.control.Label
        GridLayout4_2                matlab.ui.container.GridLayout
        GridLayout6                  matlab.ui.container.GridLayout
        CalculateTraceButton         matlab.ui.control.Button
        GridLayout7                  matlab.ui.container.GridLayout
        TraceLabel                   matlab.ui.control.Label
        GridLayout4                  matlab.ui.container.GridLayout
        GridLayout5                  matlab.ui.container.GridLayout
        SaveButton                   matlab.ui.control.Button
        OpenButton                   matlab.ui.control.Button
        FileLabel                    matlab.ui.control.Label
        Tab                          matlab.ui.container.Tab
        GridLayout9                  matlab.ui.container.GridLayout
        TraceContextMenu             matlab.ui.container.ContextMenu
        DeleteTrace                  matlab.ui.container.Menu
    end

    
    properties (Access = private)
       
        TreePanel           matlab.ui.container.Panel
        TreeGridLayout      matlab.ui.container.GridLayout
        Tree                matlab.ui.container.CheckBoxTree
        ChartsGridLayout    matlab.ui.container.GridLayout
        Chart               matlab.ui.control.UIAxes
        FFTChart            matlab.ui.control.UIAxes
        isChart
        isfftChart
        isIFFTChart
        DeleteMenu          matlab.ui.container.Menu
        IFFTChart           matlab.ui.control.UIAxes
        CHOOSE_SOME_DATA
        traceAdderApp
        Texts
        CsvIcon
        TraceIcon
        logAppHandle
        IFFTGroup
        SmoothGroup
        AllElementsGroup
    end
    
    methods (Access = private)

        function Texts = readTexts(app, Language)
            switch lower(Language)
                case 'en'
                    file_path = 'Texts\OSA_Data_Explorer\EN.xlsx';
                    logAlert(app,'English language was selected');
                case 'pl'
                    file_path = 'Texts\OSA_Data_Explorer\PL.xlsx';
                    logAlert(app,'Polish language was selected');
                otherwise
                    % error('Nieobsługiwany język');
                    logAlert(app, '[ERROR] Selected language not supported, default language: English');
                    file_path = 'Texts\OSA_Data_Explorer\EN.xlsx';
                    logAlert(app,'English language was selected');
                    % msgbox("vdfxxv")
            end
            data = readtable(file_path);
            logAlert(app,'Reading language is succesful');
            Texts = struct();
            for i = 1:height(data)
                key = data.Key{i};
                translation = data.Translation{i};
                Texts.(key) = string(translation);
            end
        end
        function logAlert(app,message)
            fid = fopen('log.txt', 'a');
            if fid == -1
                error('Nie można otworzyć pliku do zapisu.');
            end
            timestamp = datestr(now, 'yyyy-MM-dd HH:mm:ss.FFF');
            logMessage = sprintf('[%s] >>> %s\n', timestamp, char(message));
            fprintf(fid, '%s', logMessage);
            fclose(fid);

            % 
            % if isvalid(app.logAppHandle)
            %     currentLog = app.logAppHandle.TextArea.Value;
            %     disp(currentLog)
            %     app.logAppHandle.TextArea.Value = [currentLog;message];
            % end
        
        end
        function textChange(app)
            app.HomeTab.Title = app.Texts.HOME_TAB;
            app.FileLabel.Text = app.Texts.FILE_LABEL;
            app.OpenButton.Text = app.Texts.OPEN_BUTTON;
            app.SaveButton.Text = app.Texts.SAVE_BUTTON;
            app.CalculateTraceButton.Text = app.Texts.ADD_TRACE_BUTTON;
            app.FFTButton.Text = app.Texts.FFT_BUTTON;
            app.IFFTButton.Text = app.Texts.IFFT_BUTTON;
            app.NumberofharmoicSpinnerLabel.Text = app.Texts.NUMBER_OF_HARMOIC_SPINNER_LABEL;
            app.oneharmonicButton.Text = app.Texts.ONE_HARMOIC_BUTTON;
            app.NormalizedButton.Text = app.Texts.NORMALIZE_BUTTON;
            app.SmoothLabel.Text = "blabla";
            app.FitCurveButton.Text = app.Texts.FIT_CURVE_BUTTON;
            app.FSRButton.Text = app.Texts.FSR_BUTTON;
            app.MainInfo.Text = app.Texts.MAIN_INFO_IMPORT_SOME_DATA;
            app.DeleteTrace.Text = app.Texts.DELETE_TRACE;
        end
        
        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            refreshCharts(app);
        end
        function TreeClicked(app, event)
            if isempty(event.InteractionInformation.Node)
                app.CalculateTraceButton.Enable="off";
            else
                if isempty(event.InteractionInformation.Node.Children)
                    logAlert(app,"Trace was selected");
                    app.CalculateTraceButton.Enable="off";
                else
                    logAlert(app,"csv file was selected");
                    app.CalculateTraceButton.Enable="on";
                end
            end
            refreshCharts(app);
        end
        
        % create components
        function status = creteFilesAndTraceTree(app)
            status = false;
            try % TreePanel
                app.TreePanel = uipanel(app.PanelsGridLayout);
                app.TreePanel.Title = app.Texts.TREE_PANEL_LABEL_NAME;
                app.TreePanel.Layout.Row = 1;
                app.TreePanel.Layout.Column = 1;
                app.TreePanel.Visible = "off";
                try % TreeGridLayout
                    app.TreeGridLayout = uigridlayout(app.TreePanel);
                    app.TreeGridLayout.ColumnWidth = {'1x'};
                    app.TreeGridLayout.RowHeight = {'1x'};
                    app.TreeGridLayout.ColumnSpacing = 0;
                    app.TreeGridLayout.RowSpacing = 0;
                    app.TreeGridLayout.Padding = [0 0 0 0];
                    try %Tree
                        app.Tree = uitree(app.TreeGridLayout, 'checkbox');
                        app.Tree.Layout.Row = 1;
                        app.Tree.Layout.Column = 1;
                        app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app,@TreeCheckedNodesChanged,true);
                        app.Tree.ClickedFcn = createCallbackFcn(app, @TreeClicked, true);
                        status = true;
                    catch
                        warning("creteFilesAndTraceTree: crete files & Trace Tree fault");
                    end
                catch
                    warning("creteFilesAndTraceTree: crete files & Trace Tree grid layout fault");
                end
            catch
                warning("creteFilesAndTraceTree: crete files & Trace Tree panel fault");
            end   
        end
        function createChartsGrid(app)
            app.ChartsGridLayout = uigridlayout(app.PanelsGridLayout);
            app.ChartsGridLayout.ColumnWidth = {'1x'};
            app.ChartsGridLayout.RowHeight = {'1x'};
            app.ChartsGridLayout.ColumnSpacing = 0;
            app.ChartsGridLayout.RowSpacing = 5;
            app.ChartsGridLayout.Padding = [5 5 5 5];
            app.ChartsGridLayout.Layout.Row = 1;
            app.ChartsGridLayout.Layout.Column = 2;
            logAlert(app,'Charts grid was created');
        end
        function createChart(app)
            app.Chart = uiaxes(app.ChartsGridLayout);
            xlabel(app.Chart, app.Texts.SPECTRAL_CHART_AXIS_X_LABEL)
            ylabel(app.Chart, app.Texts.SPECTRAL_CHART_AXIS_Y_LABEL)
            app.Chart.Layout.Row = length(app.ChartsGridLayout.RowHeight);
            app.Chart.Layout.Column = 1;
            hold(app.Chart,"on")
            logAlert(app,'Spectral char was created');
        end
        function createFFTChart(app)
            app.FFTChart = uiaxes(app.ChartsGridLayout);
            xlabel(app.FFTChart, app.Texts.FFT_CHART_AXIS_X_LABEL)
            ylabel(app.FFTChart, app.Texts.FFT_CHART_AXIS_Y_LABEL)
            app.FFTChart.Layout.Row = length(app.ChartsGridLayout.RowHeight);
            app.FFTChart.Layout.Column = 1;
            hold(app.FFTChart,"on")
            logAlert(app,'FFT chart was created');
        end
        function createIFFTChart(app)
            app.IFFTChart = uiaxes(app.ChartsGridLayout);
            xlabel(app.IFFTChart, app.Texts.IFFT_CHART_AXIS_X_LABEL)
            ylabel(app.IFFTChart, app.Texts.IFFT_CHART_AXIS_Y_LABEL)
            app.IFFTChart.Layout.Row = length(app.ChartsGridLayout.RowHeight);
            app.IFFTChart.Layout.Column = 1;
            hold(app.IFFTChart,"on")
            logAlert(app,'IFFT chart was created');
        end

        function peaksPlot(app,Level,data)
            [peaks, locs_peaks] = findpeaks(Level);
            [troughs, locs_troughs] = findpeaks(-Level);

            points = locs_peaks;  % Możesz zmienić na swoje punkty
            x_coordsIndex = points(:, 1);

            x_coords = data.Wavelength(x_coordsIndex);

            disp(x_coords)
            x_diffs = diff(x_coords);
            disp("---------------------");
            disp(x_diffs);
            disp("---------------------");
            disp("**************************")
            
            % Wyświetlamy wyniki
            disp('Odległości w osi X pomiędzy kolejnymi punktami:');
            disp(x_diffs);
            disp("avg");
            disp(mean(x_diffs));


            plot(app.Chart, data.Wavelength(locs_peaks), peaks, 'ro',"MarkerSize",5);
            plot(app.Chart, data.Wavelength(locs_troughs), -troughs, 'go',"MarkerSize",5);
        end
        
        function basicChartServices(app,data,color)
            Level = data.Level;
            origin = plot(app.Chart,data.Wavelength,Level,"Color",color,"DisplayName",data.name);
            if app.FitCurveButton.Value
                origin.Color = [color,0.05];
                Level = fitCurve(Level,app.FitSpinner.Value);
                plot(app.Chart,data.Wavelength,Level,"Color",color)
                app.FSRButton.Enable = "on";
                app.FitSpinner.Enable = "on";
                if app.FSRButton.Value
                    peaksPlot(app,Level,data)
                end
            end
        end
        function fftChartServices(app,data,Level,color)
            try
                Fs = 1/((data.Wavelength(2)*1e-9)-(data.Wavelength(1)*1e-9));
                fourierTransform = fft(Level);
                N = length(fourierTransform);
                halfN = round(N/2);
                frequencies = linspace(0, Fs/2, halfN);
                fourierTransformHalf = fourierTransform(1:halfN);
                % fourierTransformHalfNormalized = abs(fourierTransformHalf)/max(abs(fourierTransformHalf));
                stem(app.FFTChart,frequencies, abs(fourierTransformHalf),"Color",color,"DisplayName",data.name,"LineWidth",2);
                % plot(app.FFTChart,frequencies, 20 * log10(abs(fft_result(half_len+1:end))))
            catch
                warning('fftChartServices: fault');
            end
        end
        function ifftChartServices(app,data,Level,color)
            fourierTransform = fft(Level);
            [~, topThreeIndices] = maxk(abs(fourierTransform), app.NumberofharmoicSpinner.Value);
            fourierTransformFiltered = zeros(size(fourierTransform));
            if app.oneharmonicButton.Value
                fourierTransformFiltered(topThreeIndices(end)) = fourierTransform(topThreeIndices(end));
            else
                fourierTransformFiltered(topThreeIndices) = fourierTransform(topThreeIndices);
            end
            reconstructed_signal = ifft(fourierTransformFiltered);
            if app.NormalizedButton.Value
                reconstructed_signal = reconstructed_signal/max(abs(reconstructed_signal));
            end
            t_reconstructed = linspace(data.Wavelength(1),data.Wavelength(end),length(reconstructed_signal));
            plot(app.IFFTChart,t_reconstructed, real(reconstructed_signal),"Color",color,"DisplayName",data.name);
        end

        function refreshCharts(app)
            set(app.AllElementsGroup, 'Enable', 'off');
            
            CheckedNodes = app.Tree.CheckedNodes;
            
            if ~isempty(CheckedNodes)
                try % basic chart clear or create
                    if app.isChart
                        cla(app.Chart);
                    else
                        createChartsGrid(app)
                        createChart(app);
                        app.isChart = true;
                    end
                catch
                    warning('refreshCharts: basic chart clear or create fault');
                end
                try % FFT chart clear
                    if app.isfftChart
                        cla(app.FFTChart);
                    end
                catch
                    warning('refreshCharts: basic chart clear or create fault');
                end
                try % IFFT chart clear
                    if app.isIFFTChart
                        cla(app.IFFTChart);
                    end
                catch
                    warning('refreshCharts: basic chart clear or create fault');
                end

                colors = jet(length(CheckedNodes));
                for i = 1:length(CheckedNodes)
                    if isempty(CheckedNodes(i).Children) 
                        color = colors(i,:);
                        data = CheckedNodes(i).NodeData;
                        if ~isempty(data)
                            % try % basic chart
                                basicChartServices(app,data,color);
                            % catch
                            %     warning('refreshCharts: Basic chart fault');
                            % end
                            try % FFT
                                if app.FFTButton.Value
                                    fftChartServices(app,data,data.Level,color);
                                end
                            catch
                                warning('refreshCharts: FFT fault');
                            end
                            try % IFFT
                                if app.IFFTButton.Value
                                    ifftChartServices(app,data,data.Level,color)
                                    set(app.IFFTGroup, 'Enable', 'on');
                                else
                                    set(app.IFFTGroup, 'Enable', 'off');
                                end
                            catch
                                warning('refreshCharts: IFFT fault');
                            end
                        else
                            warning('refreshCharts: Data is empty')
                        end
                    end
                end

                app.Chart.Visible="on";
                app.FFTButton.Enable="on";
                app.IFFTButton.Enable="on";
                app.FitCurveButton.Enable="on";
            else
                app.FFTButton.Enable="off";
                app.IFFTButton.Enable="off";
                app.FitCurveButton.Enable="off";
                delete(app.Chart)
                delete(app.FFTChart)
                delete(app.IFFTChart)
                delete(app.ChartsGridLayout);
                app.isChart = false;
                app.isfftChart = false;
                app.isIFFTChart = false;
            end
            drawnow 
            app.Tree.Enable = "on";
        end
    end
    
    methods (Access = public)

        function dfg(app)
            Trace = app.traceAdderApp.newTrace;
            SelectedFile = app.Tree.SelectedNodes;
            uitreenode(SelectedFile, 'Text', app.traceAdderApp.Name,'Icon',app.TraceIcon,'NodeData',Trace,'ContextMenu',app.TraceContextMenu);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.OSAdataexplorerUIFigure.Visible="off";

            app.Texts = readTexts(app, 'EN');
            textChange(app)

            app.CsvIcon = "Icons\Tree_CSV_File_Icon.png";
            app.TraceIcon = "Icons\Tree_Trace_Icon.png";
            
            app.HomeTab.UserData = 100;
            app.isChart = false;
            app.isfftChart = false;
            app.isIFFTChart = false;


            app.SmoothGroup = [app.FitCurveButton,app.FitSpinner,app.FSRButton];
            app.IFFTGroup = [app.NumberofharmoicSpinner,app.oneharmonicButton,app.NormalizedButton];
            app.AllElementsGroup = [app.OpenButton ,app.Tree,app.SmoothGroup,app.FFTButton,app.IFFTButton,app.IFFTGroup];

            set(app.AllElementsGroup, 'Enable', 'off');
            app.OpenButton.Enable = "on";


            ScrSize = get(0, 'ScreenSize');
            FigSize = app.OSAdataexplorerUIFigure.Position;
            set(app.OSAdataexplorerUIFigure, 'Position', [(ScrSize(3) - FigSize(3)) / 2, (ScrSize(4) - FigSize(4)) / 2, FigSize(3), FigSize(4)]);

            app.OSAdataexplorerUIFigure.Visible="on";


        end

        % Button down function: HomeTab
        function HomeTabButtonDown(app, event)
            % if app.HomeTab.UserData > 100
            %     setToolbarHeight(app,25);
            % else
            %     setToolbarHeight(app,125);
            % end
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            app.OpenButton.Enable = "off";
            try % load data
                [FilesNameList, FilesPath] = uigetfile({'*.csv';'*.txt'}, 'Select File(s)', 'MultiSelect', 'on');
                app.OSAdataexplorerUIFigure.WindowStyle = 'alwaysontop';
                if iscell(FilesNameList)
                    FilesNameList = FilesNameList';
                end
                filesList = string(FilesNameList);
                if creteFilesAndTraceTree(app)
                    try % create nodes
                        for F = 1:length(filesList)
                            uitreenode(app.Tree, 'Text', filesList(F),"Icon",app.CsvIcon,'ContextMenu',app.TraceContextMenu);
                            FileNode = app.Tree.Children(end);
                            s = struct();
                            extractDataFromOSA(FilesPath, filesList(F));
                            s.Traces = getTofDataFromCsvFile(filesList(F));
                            for T = 1:length(s.Traces)
                                traceName = s.Traces{T}.name;
                                uitreenode(FileNode, 'Text', traceName,'Icon',app.TraceIcon,'NodeData',s.Traces{T},'ContextMenu',app.TraceContextMenu);
                            end

                            refLevel = s.Traces{1}.Level;
                            for TT = 2:length(s.Traces)
                                traceName = s.Traces{TT}.name;
                                
                                leftTraceLevel = s.Traces{TT}.Level;
                                rightTraceLevel = refLevel;
                                LevelXXX = real(-10 * log(leftTraceLevel./rightTraceLevel));

                                newTraceName = "";
                                if traceName == "Level_B_"
                                    newTraceName = "1xTOF";
                                elseif traceName == "Level_C_"
                                    newTraceName = "2xTOF";
                                elseif traceName == "Level_D_"
                                    newTraceName = "3xTOF";
                                else
                                    break
                                end
                                
                                fNX = filesList{F};
                                % FGH = fileNameXFG{10};
                                lenF = length(fNX);
                                newTraceName = fNX(1:2) + "mW " + newTraceName + " v" + fNX(lenF-4);


                                STrace = s.Traces{TT};
                                STrace.Level = LevelXXX;
                                STrace.name = newTraceName;
                                uitreenode(FileNode, 'Text', newTraceName,'Icon',app.TraceIcon,'NodeData',STrace,'ContextMenu',app.TraceContextMenu);
                            end

                            % for TTT = 1:length(FileNode.Children)-1

                            %     delete(FileNode.Children(TTT));
                            % end
                            
                        end
                    catch
                        warning('OpenButtonPushed: files & trace tree node add fault');
                    end
                    app.TreePanel.Visible = "on";
                    app.MainInfo.Text = app.Texts.MAIN_INFO_CHOOSE_SOME_DATA;
                end
            catch
                warning('OpenButtonPushed: load data fault')
            end
            app.OpenButton.Enable = "on";
        end

        % Value changed function: FitSpinner
        function FitSpinnerValueChanged(app, event)
            refreshCharts(app)
        end

        % Value changed function: FFTButton
        function FFTButtonValueChanged(app, event)
            try
                if app.FFTButton.Value
                    GridOperations(app.ChartsGridLayout,"Type","Row","Operation","Add")
                    createFFTChart(app)
                    app.isfftChart = true;
                else
                    delete(app.FFTChart)
                    GridOperations(app.ChartsGridLayout,"Type","Row","Operation","Delete")
                    app.isfftChart = false;
                end
                refreshCharts(app)
            catch
                warning('FFTButtonValueChanged: Fault');
            end
        end

        % Value changed function: IFFTButton
        function IFFTButtonValueChanged(app, event)
            try    
                if app.IFFTButton.Value
                    GridOperations(app.ChartsGridLayout,"Type","Row","Operation","Add")
                    createIFFTChart(app)
                    app.isIFFTChart = true;
                else
                    delete(app.IFFTChart)
                    GridOperations(app.ChartsGridLayout,"Type","Row","Operation","Delete")
                    app.isIFFTChart = false;
                end
                refreshCharts(app)
            catch
                warning('IFFTButtonValueChanged: Fault');
            end
        end

        % Window button down function: OSAdataexplorerUIFigure
        function OSAdataexplorerUIFigureWindowButtonDown(app, event)
            app.OSAdataexplorerUIFigure.WindowStyle = 'normal';
        end

        % Menu selected function: DeleteTrace
        function DeleteTraceMenuSelected(app, event)
            try
                delete(event.ContextObject)
            catch
                warning('DeleteTraceMenuSelected: fault');
            end
        end

        % Value changing function: FitSpinner
        function FitSpinnerValueChanging(app, event)
            refreshCharts(app)
        end

        % Value changed function: NumberofharmoicSpinner
        function NumberofharmoicSpinnerValueChanged(app, event)
            refreshCharts(app)
        end

        % Value changed function: oneharmonicButton
        function oneharmonicButtonValueChanged(app, event)
            refreshCharts(app)
        end

        % Value changed function: NormalizedButton
        function NormalizedButtonValueChanged(app, event)
            refreshCharts(app)
        end

        % Button pushed function: CalculateTraceButton
        function CalculateTraceButtonPushed(app, event)
            try
                SelectedFile = app.Tree.SelectedNodes;
                Traces = SelectedFile.Children;
                try % add trace app start
                    app.traceAdderApp = addTrace(app,Traces);
                catch
                    warning('CalculateTraceButtonPushed: add trace app start fault');
                end
            catch
                warning('CalculateTraceButtonPushed: fault');
            end
        end

        % Callback function
        function LogButtonPushed(app, event)
            app.logAppHandle = logApp;
        end

        % Value changed function: LegendButton
        function LegendButtonValueChanged(app, event)
            value = app.LegendButton.Value;
            lgd = legend(app.Chart, 'show');
            lgd.FontSize = 15;
            % legend(app.IFFTChart, 'show');
            % legend(app.FFTChart, 'show');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OSAdataexplorerUIFigure = uifigure('Visible', 'off');
            app.OSAdataexplorerUIFigure.Name = 'OSA data explorer';
            app.OSAdataexplorerUIFigure.WindowButtonDownFcn = createCallbackFcn(app, @OSAdataexplorerUIFigureWindowButtonDown, true);
            app.MainGridLayout = uigridlayout(app.OSAdataexplorerUIFigure);
            app.TabGroup = uitabgroup(app.MainGridLayout);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.HomeTab.ButtonDownFcn = createCallbackFcn(app, @HomeTabButtonDown, true);
            app.HomeGrid = uigridlayout(app.HomeTab);
            app.Home2Grid = uigridlayout(app.HomeGrid);
            app.GridLayout4 = uigridlayout(app.Home2Grid);
            app.FileLabel = uilabel(app.GridLayout4);
            app.FileLabel.Text = 'File';
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.OpenButton = uibutton(app.GridLayout5, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.SaveButton = uibutton(app.GridLayout5, 'push');
            app.SaveButton.Enable = 'off';
            app.SaveButton.Tooltip = {'Funkcja jeszcze nie dostępna'};
            app.SaveButton.Text = 'Save';
            app.GridLayout4_2 = uigridlayout(app.Home2Grid);
            app.TraceLabel = uilabel(app.GridLayout4_2);
            app.TraceLabel.Text = 'Trace';
            app.GridLayout6 = uigridlayout(app.GridLayout4_2);
            app.GridLayout7 = uigridlayout(app.GridLayout6);
            app.CalculateTraceButton = uibutton(app.GridLayout6, 'push');
            app.CalculateTraceButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateTraceButtonPushed, true);
            app.CalculateTraceButton.Enable = 'off';
            app.CalculateTraceButton.Tooltip = {'Oblicz nowy przebieg (tu możesz odjąć jeden od drugiego)'};
            app.CalculateTraceButton.Text = 'Calculate Trace';
            app.FourierGrid = uigridlayout(app.Home2Grid);
            app.FourierTransformsLabel = uilabel(app.FourierGrid);
            app.FourierTransformsLabel.Text = 'Fourier Transforms';
            app.FourierGrid2 = uigridlayout(app.FourierGrid);
            app.FFTButton = uibutton(app.FourierGrid2, 'state');
            app.FFTButton.ValueChangedFcn = createCallbackFcn(app, @FFTButtonValueChanged, true);
            app.FFTButton.Enable = 'off';
            app.FFTButton.Tooltip = {'Oblicz transformte fouriera'};
            app.FFTButton.Text = 'FFT';
            app.GridLayout10 = uigridlayout(app.FourierGrid2);
            app.IFFTButton = uibutton(app.GridLayout10, 'state');
            app.IFFTButton.ValueChangedFcn = createCallbackFcn(app, @IFFTButtonValueChanged, true);
            app.IFFTButton.Enable = 'off';
            app.IFFTButton.Tooltip = {'Oblicz odwrotną transformte fouriera'; '(rekonstrukcja sygnału, zwiększ liczbe harmonicznych)'};
            app.IFFTButton.Text = 'IFFT';
            app.GridLayout11 = uigridlayout(app.FourierGrid2);
            app.NumberofharmoicSpinnerLabel = uilabel(app.GridLayout11);
            app.NumberofharmoicSpinnerLabel.Enable = 'off';
            app.NumberofharmoicSpinnerLabel.Tooltip = {'Liczba harmoinicznych użytych do rekonstrukcji sygnału (Pamięteaj, Pierwsza harmoniczna jest składową stałą :D)'};
            app.NumberofharmoicSpinnerLabel.Text = 'Number of harmoic';
            app.NumberofharmoicSpinner = uispinner(app.GridLayout11);
            app.NumberofharmoicSpinner.Limits = [1 Inf];
            app.NumberofharmoicSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofharmoicSpinnerValueChanged, true);
            app.NumberofharmoicSpinner.Enable = 'off';
            app.NumberofharmoicSpinner.Value = 1;
            app.oneharmonicButton = uibutton(app.GridLayout11, 'state');
            app.oneharmonicButton.ValueChangedFcn = createCallbackFcn(app, @oneharmonicButtonValueChanged, true);
            app.oneharmonicButton.Enable = 'off';
            app.oneharmonicButton.Text = 'one harmonic';
            app.NormalizedButton = uibutton(app.GridLayout11, 'state');
            app.NormalizedButton.ValueChangedFcn = createCallbackFcn(app, @NormalizedButtonValueChanged, true);
            app.NormalizedButton.Enable = 'off';
            app.NormalizedButton.Text = 'Normalized';
            app.TypeOfTraceGrid = uigridlayout(app.Home2Grid);
            app.SmoothLabel = uilabel(app.TypeOfTraceGrid);
            app.SmoothLabel.Text = 'Smooth';
            app.GridLayout12 = uigridlayout(app.TypeOfTraceGrid);
            app.FitCurveButton = uibutton(app.GridLayout12, 'state');
            app.FitCurveButton.Text = 'Fit Curve';
            app.FSRButton = uibutton(app.GridLayout12, 'state');
            app.FSRButton.Text = 'FSR';
            app.FitSpinner = uispinner(app.GridLayout12);
            app.FitSpinner.ValueChangingFcn = createCallbackFcn(app, @FitSpinnerValueChanging, true);
            app.FitSpinner.Limits = [1 Inf];
            app.FitSpinner.ValueChangedFcn = createCallbackFcn(app, @FitSpinnerValueChanged, true);
            app.FitSpinner.Value = 1;
            app.GridLayout13 = uigridlayout(app.Home2Grid);
            app.LegendButton = uibutton(app.GridLayout13, 'state');
            app.LegendButton.ValueChangedFcn = createCallbackFcn(app, @LegendButtonValueChanged, true);
            app.LegendButton.Text = 'Legend';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.GridLayout9 = uigridlayout(app.Tab);
            app.PanelsGridLayout = uigridlayout(app.MainGridLayout);
            app.MainInfo = uilabel(app.PanelsGridLayout);
            app.MainInfo.Text = 'Import some data';
            app.TraceContextMenu = uicontextmenu(app.OSAdataexplorerUIFigure);
            app.DeleteTrace = uimenu(app.TraceContextMenu);
            app.DeleteTrace.MenuSelectedFcn = createCallbackFcn(app, @DeleteTraceMenuSelected, true);
            app.DeleteTrace.Text = 'Delete';
            % Show the figure after all components are created
            app.OSAdataexplorerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OSA_dataExplorer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OSAdataexplorerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OSAdataexplorerUIFigure)
        end
    end
end