classdef addTrace < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TraceadderUIFigure    matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        GridLayout4           matlab.ui.container.GridLayout
        CloseandexportButton  matlab.ui.control.Button
        NameEditField         matlab.ui.control.EditField
        NameEditFieldLabel    matlab.ui.control.Label
        GridLayout2           matlab.ui.container.GridLayout
        TreeRight             matlab.ui.container.Tree
        TreeLeft              matlab.ui.container.Tree
        GridLayout3           matlab.ui.container.GridLayout
        Button                matlab.ui.control.StateButton
    end

    
    properties (Access = public)
        MainAppHandle
    
        Name
        newTrace
    end
    
    methods (Access = private)
        
        function func(app)
            leftTrace = app.TreeLeft.SelectedNodes;
            rightTrace = app.TreeRight.SelectedNodes;
            if leftTrace ~= 0 & rightTrace ~= 0
                app.NameEditField.Value = [leftTrace.Text,'–',rightTrace.Text];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp, Traces)
            app.MainAppHandle = MainApp;
            app.CloseandexportButton.Text = fileread('Texts/TraceAdder/CLOSE_BUTTON.txt');
            app.NameEditFieldLabel.Text = fileread('Texts/TraceAdder/NAME_FIELD.txt');
            app.NameEditField.Tooltip = fileread('Texts/TraceAdder/NAME_FIELD_TOOLTIP.txt');
            for i = 1:length(Traces)
                % disp(Traces(i).Text)
                uitreenode(app.TreeLeft, 'Text', Traces(i).Text,'NodeData',Traces(i).NodeData);
                uitreenode(app.TreeRight, 'Text', Traces(i).Text,'NodeData',Traces(i).NodeData);
            end
        end

        % Button pushed function: CloseandexportButton
        function CloseandexportButtonPushed(app, event)
            leftTrace = app.TreeLeft.SelectedNodes;
            rightTrace = app.TreeRight.SelectedNodes;
            if leftTrace ~= 0 && rightTrace ~= 0
                leftTraceLevel = leftTrace.NodeData.Level;
                rightTraceLevel = rightTrace.NodeData.Level;
                Level = real(-10 * log(leftTraceLevel./rightTraceLevel));
            end
            app.Name = app.NameEditField.Value;
            app.newTrace = struct('name',app.Name,'Wavelength',leftTrace.NodeData.Wavelength,'Level',Level);
            app.MainAppHandle.dfg();
        end

        % Selection changed function: TreeLeft
        function TreeLeftSelectionChanged(app, event)
            func(app);
        end

        % Selection changed function: TreeRight
        function TreeRightSelectionChanged(app, event)
            func(app)
        end

        % Close request function: TraceadderUIFigure
        function TraceadderUIFigureCloseRequest(app, event)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.TraceadderUIFigure = uifigure('Visible', 'off');
            app.TraceadderUIFigure.Name = 'Trace adder';
            app.TraceadderUIFigure.Icon = fullfile(pathToMLAPP, 'Icons', 'Tree_Trace_Icon.png');
            app.TraceadderUIFigure.CloseRequestFcn = createCallbackFcn(app, @TraceadderUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.TraceadderUIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.Button = uibutton(app.GridLayout3, 'state');
            app.Button.Enable = 'off';
            app.Button.Text = '-';
            app.Button.Value = true;
            app.TreeLeft = uitree(app.GridLayout2);
            app.TreeLeft.SelectionChangedFcn = createCallbackFcn(app, @TreeLeftSelectionChanged, true);
            app.TreeRight = uitree(app.GridLayout2);
            app.TreeRight.SelectionChangedFcn = createCallbackFcn(app, @TreeRightSelectionChanged, true);
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.NameEditFieldLabel = uilabel(app.GridLayout4);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.GridLayout4, 'text');
            app.NameEditField.Tooltip = {'Wpisz tu coś'};
            app.CloseandexportButton = uibutton(app.GridLayout4, 'push');
            app.CloseandexportButton.ButtonPushedFcn = createCallbackFcn(app, @CloseandexportButtonPushed, true);
            app.CloseandexportButton.Text = 'Close and export';
            % Show the figure after all components are created
            app.TraceadderUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = addTrace(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.TraceadderUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.TraceadderUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TraceadderUIFigure)
        end
    end
end